classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        cEditField                     matlab.ui.control.NumericEditField
        cEditField_2Label              matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        ComputeButton_2                matlab.ui.control.Button
        PhiEditField_2                 matlab.ui.control.NumericEditField
        PhiEditField_3Label            matlab.ui.control.Label
        ThetaEditField_2               matlab.ui.control.NumericEditField
        ThetaEditField_3Label          matlab.ui.control.Label
        AlphaEditField_2               matlab.ui.control.NumericEditField
        AlphaEditField_3Label          matlab.ui.control.Label
        PhiEditField_1                 matlab.ui.control.NumericEditField
        PhiEditField_2Label            matlab.ui.control.Label
        ThetaEditField_1               matlab.ui.control.NumericEditField
        ThetaEditField_2Label          matlab.ui.control.Label
        AlphaEditField_1               matlab.ui.control.NumericEditField
        AlphaEditField_2Label          matlab.ui.control.Label
        PhiEditField                   matlab.ui.control.NumericEditField
        PhiEditFieldLabel              matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        Lim2Label                      matlab.ui.control.Label
        Lim1Label                      matlab.ui.control.Label
        NumberofpointsLabel            matlab.ui.control.Label
        AngularWeightingFunctionLabel  matlab.ui.control.Label
        GeometryLabel                  matlab.ui.control.Label
        FoVSourceEditField             matlab.ui.control.NumericEditField
        FoVSourceEditFieldLabel        matlab.ui.control.Label
        FoVDetectorEditField           matlab.ui.control.NumericEditField
        FoVDetectorEditFieldLabel      matlab.ui.control.Label
        ExyzWm2EditField               matlab.ui.control.EditField
        ExyzLabel                      matlab.ui.control.Label
        sigmaxyzEditField              matlab.ui.control.EditField
        sigmaxyzEditFieldLabel         matlab.ui.control.Label
        SourceandDetectorLabel         matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        dmmEditField                   matlab.ui.control.NumericEditField
        dLabel                         matlab.ui.control.Label
        alpha_2degreesEditField        matlab.ui.control.NumericEditField
        alpha_2degreesEditFieldLabel   matlab.ui.control.Label
        alpha_1degreesEditField        matlab.ui.control.NumericEditField
        alpha_1Label                   matlab.ui.control.Label
        ParametersLabel                matlab.ui.control.Label
        InfoButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Parámetros geométricos 
        a1 
        a2
        d
        
        %% Funciones Handle
        
        Source
        Detector
        Sf
        Df
        Rang

        ideal
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value(1)=='N'
                app.sigmaxyzEditField.Enable='on';
                app.ExyzWm2EditField.Enable='on';
                app.FoVDetectorEditField.Enable='off';
                app.FoVSourceEditField.Enable='off';
            else
                app.sigmaxyzEditField.Enable='off';
                app.ExyzWm2EditField.Enable='off';
                app.FoVDetectorEditField.Enable='on';
                app.FoVSourceEditField.Enable='on';
            end
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
            syms x y z

            %Se asignan variables a los valores que se han escrito en los
            %distintos campos
            app.a1 =app.alpha_1degreesEditField.Value;
            app.a2 =app.alpha_2degreesEditField.Value;
            app.d =app.dmmEditField.Value;
            
            % Rotate Emission Pattern
            xs=-sind(app.a1).*x+cosd(app.a1).*z;
            ys=y;
            zs=cosd(app.a1).*x+sind(app.a1).*z;
            
            % Rotation and traslation of the Angular sensitivity of the Source 
            xd=sind(app.a2).*(x-app.d)+cosd(app.a2).*z;
            yd=y;
            zd=-cosd(app.a2).*(x-app.d)+sind(app.a2).*z;

            value = app.Switch.Value;
            if value(1)=='N'
                app.ideal=0;
                %Se transforman las expresiones de los cuadros en funciones
                app.Detector = matlabFunction(str2sym(app.sigmaxyzEditField.Value));
                app.Source = matlabFunction(str2sym(app.ExyzWm2EditField.Value));

                app.Df=matlabFunction(app.Detector(xd,yd,zd));
                app.Sf=matlabFunction(app.Source(xs,ys,zs));
                

                %Se crea un meshgrid para dibujar las funciones
                [Xf,Yf]=meshgrid(0.1:0.1:app.d,0.1:0.1:app.d);
                Fd=app.Df(Xf,0.1,Yf);
                Fs=app.Sf(Xf,0.1,Yf);

                %representar y que esten equiespaciadas
                LvlxFd=linspace(0.1+app.d,-1.4*app.d*cosd(app.a2)+(app.d),10);
                LvlzFd=linspace(0.1,1.4*app.d*sind(app.a2),10);
                LvlxFs=linspace(0.1,1.4*app.d*cosd(app.a1),10);
                LvlzFs=linspace(0.1,1.4*app.d*sind(app.a1),10);

                LvlFd=app.Df(LvlxFd,0,LvlzFd);
                LvlFs=app.Sf(LvlxFs,0,LvlzFs);

                hold(app.UIAxes2,'off')
                contour(app.UIAxes2,Xf,Yf,log2(abs(Fd)),log2(abs(LvlFd)),'LineColor','g');
                hold(app.UIAxes2,'on')
                contour(app.UIAxes2,Xf,Yf,log2(abs(Fs)),log2(abs(LvlFs)),'LineColor','r');
                axis(app.UIAxes2,'equal')
                xlabel(app.UIAxes2,'X (mm)')
                ylabel(app.UIAxes2,'Y (mm)')
                title(app.UIAxes2,'Geometry')

                legend(app.UIAxes2,'Source','Detector')

            else
                app.ideal=1;
                fovs=app.FoVSourceEditField.Value;
                fovd=app.FoVDetectorEditField.Value;
                app.Sf=matlabFunction((y.^2/sind(fovs)^2+(cosd(app.a1)*z-sind(app.a1)*x).^2/sind(fovs)^2-(-cosd(app.a1)*x-sind(app.a1)*z).^2/cosd(fovs)^2<0));
                app.Df=matlabFunction((y.^2/sind(fovd)^2+(cosd(app.a2)*z+sind(app.a2)*(x-app.d)).^2/sind(fovd)^2-(-cosd(app.a2)*(x-app.d)+sind(app.a2).*z).^2/cosd(fovd)^2<0));
                app.Rang         = matlabFunction((-cosd(app.a2).*(x-app.d)+sind(app.a2).*(z))./sqrt((x-app.d).^2+y.^2+z.^2));
                hold(app.UIAxes2,'off')
                plot(app.UIAxes2,[0 2*app.d*cosd(app.a1-fovs)],[0 2*app.d*sind(app.a1-fovs)],'r-');
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,[0 2*app.d*cosd(app.a1+fovs)],[0 2*app.d*cosd(app.a1+fovs)],'r-');
                plot(app.UIAxes2,[app.d app.d-2*app.d*cosd(app.a2-fovd)],[0 2*app.d*sind(app.a2-fovd)],'g-');
                plot(app.UIAxes2,[app.d app.d-2*app.d*cosd(app.a2+fovd)],[0 2*app.d*sind(app.a2+fovd)],'g-');

                axis(app.UIAxes2,'equal')
                xlabel(app.UIAxes2,'X (mm)')
                ylabel(app.UIAxes2,'Y (mm)')
                title(app.UIAxes2,'Geometry')

            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
           addpath('Library') 
        value = app.Switch.Value;
            thL1= app.ThetaEditField_1.Value;
            thL2= app.ThetaEditField_2.Value;
            alL1= app.AlphaEditField_1.Value;
            alL2= app.AlphaEditField_2.Value;
            phL1= app.PhiEditField_1.Value;
            phL2= app.PhiEditField_2.Value;
            Nth = app.ThetaEditField.Value;
            Nal = app.AlphaEditField.Value;
            Nph = app.PhiEditField.Value;
            if value(1)=='N'
                [AWF, TH] = AWF_No_Ideal(thL1,thL2,alL1,alL2,phL1,phL2,Nth,Nal,Nph,app.Sf,app.Df,app.d,app.cEditField.Value);
            else
                [AWF, TH] = AWF_Ideal(thL1,thL2,alL1,alL2,phL1,phL2,Nth,Nal,Nph,app.Sf,app.Df,app.d,app.Rang);
            end
            hold(app.UIAxes,'off')
            plot(app.UIAxes, TH,AWF,'k-');
            xlabel(app.UIAxes,'\theta (degrees)')
            ylabel(app.UIAxes,'AWF (W m)')


            save(app.NameEditField.Value,"TH","AWF")
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            A = imread('Scheme.png');
            figure
            image(A)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.alpha_1Label = uilabel(app.UIFigure);
            app.alpha_1Label.Interpreter = 'latex';
            app.alpha_1Label.Text = '$\alpha_1$ (degrees)';
            app.alpha_1degreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_1degreesEditField.Value = 35;
            app.alpha_2degreesEditFieldLabel = uilabel(app.UIFigure);
            app.alpha_2degreesEditFieldLabel.Interpreter = 'latex';
            app.alpha_2degreesEditFieldLabel.Text = '$\alpha_2$ (degrees)';
            app.alpha_2degreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_2degreesEditField.Value = 35;
            app.dLabel = uilabel(app.UIFigure);
            app.dLabel.Interpreter = 'latex';
            app.dLabel.Text = '$d$ (mm)';
            app.dmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.dmmEditField.Value = 72.8;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'No Ideal', 'Ideal'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'No Ideal';
            app.SourceandDetectorLabel = uilabel(app.UIFigure);
            app.SourceandDetectorLabel.Text = 'Source and Detector';
            app.sigmaxyzEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaxyzEditFieldLabel.Interpreter = 'latex';
            app.sigmaxyzEditFieldLabel.Text = '$\sigma$ (x,y,z)';
            app.sigmaxyzEditField = uieditfield(app.UIFigure, 'text');
            app.sigmaxyzEditField.Value = '1./(x.^2+y.^2+z.^2).*((24.6/(31.74*sqrt(pi/2)))*exp(-2*((acosd(z./sqrt(x.^2+y.^2+z.^2))+8.37)/31.74).^2)+(24.6/(31.74*sqrt(pi/2)))*exp(-2*((acosd(z./sqrt(x.^2+y.^2+z.^2))-8.37)/31.74).^2)-(1.9/(7.5*sqrt(pi/2)))*exp(-2*(acosd(z./sqrt(x.^2+y.^2+z.^2))/7.5).^2))';
            app.ExyzLabel = uilabel(app.UIFigure);
            app.ExyzLabel.Interpreter = 'latex';
            app.ExyzLabel.Text = 'E (x,y,z) [$W/m^2$]';
            app.ExyzWm2EditField = uieditfield(app.UIFigure, 'text');
            app.ExyzWm2EditField.Value = '1./(x.^2+y.^2+z.^2).*(z./sqrt(x.^2+y.^2+z.^2)).^17';
            app.FoVDetectorEditFieldLabel = uilabel(app.UIFigure);
            app.FoVDetectorEditFieldLabel.Text = 'FoV (Detector)';
            app.FoVDetectorEditField = uieditfield(app.UIFigure, 'numeric');
            app.FoVDetectorEditField.Enable = 'off';
            app.FoVDetectorEditField.Value = 15;
            app.FoVSourceEditFieldLabel = uilabel(app.UIFigure);
            app.FoVSourceEditFieldLabel.Text = 'FoV (Source)';
            app.FoVSourceEditField = uieditfield(app.UIFigure, 'numeric');
            app.FoVSourceEditField.Enable = 'off';
            app.FoVSourceEditField.Value = 15;
            app.GeometryLabel = uilabel(app.UIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.AngularWeightingFunctionLabel = uilabel(app.UIFigure);
            app.AngularWeightingFunctionLabel.Text = 'Angular Weighting Function';
            app.NumberofpointsLabel = uilabel(app.UIFigure);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.Lim1Label = uilabel(app.UIFigure);
            app.Lim1Label.Text = 'Lim1';
            app.Lim2Label = uilabel(app.UIFigure);
            app.Lim2Label.Text = 'Lim2';
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField.Value = 200;
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.Value = 200;
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField.Value = 100;
            app.AlphaEditField_2Label = uilabel(app.UIFigure);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField_1.Value = -90;
            app.ThetaEditField_2Label = uilabel(app.UIFigure);
            app.ThetaEditField_2Label.Text = 'Theta';
            app.ThetaEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField_1.Value = 1;
            app.PhiEditField_2Label = uilabel(app.UIFigure);
            app.PhiEditField_2Label.Text = 'Phi';
            app.PhiEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField_1.Value = -90;
            app.AlphaEditField_3Label = uilabel(app.UIFigure);
            app.AlphaEditField_3Label.Text = 'Alpha';
            app.AlphaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField_2.Value = 90;
            app.ThetaEditField_3Label = uilabel(app.UIFigure);
            app.ThetaEditField_3Label.Text = 'Theta';
            app.ThetaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField_2.Value = 179;
            app.PhiEditField_3Label = uilabel(app.UIFigure);
            app.PhiEditField_3Label.Text = 'Phi';
            app.PhiEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField_2.Value = 90;
            app.ComputeButton_2 = uibutton(app.UIFigure, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Value = 'AWF.mat';
            app.cEditField_2Label = uilabel(app.UIFigure);
            app.cEditField_2Label.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end